classdef BlueScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        TimeScopeTab          matlab.ui.container.Tab
        AutoSetButton         matlab.ui.control.Button
        TIMEDOMAINLabel       matlab.ui.control.Label
        UIAxes3               matlab.ui.control.UIAxes
        FFTTab                matlab.ui.container.Tab
        FREQUENCYDOMAINLabel  matlab.ui.control.Label
        UIAxes4               matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: AutoSetButton
        function AutoSetButtonPushed(app, event)
            b=Bluetooth('BlueScope',1);
            fs=860;
            t=0:1/fs:1-1/fs;
            b.InputBufferSize=860;
            fopen(b);
            flushinput(b);
            x=fread(b);
            signal=6*x/255;
            s=max(signal);
            plot(app.UIAxes3,t,signal,'r');
            title(app.UIAxes3,['Amplitude = ',num2str(s),' Volts']);
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [-7 7];
            [Pxx,f] = pwelch(signal,500,300,500,fs,'centered');
            plot(app.UIAxes4,f,Pxx,'r');
            app.UIAxes4.XLim = [-120 120];
            ylabel(app.UIAxes4,'PSD'); xlabel(app.UIAxes4,'Frequency (Hz)');
            grid on;
            [~,loc] = max(Pxx);
            FREQ_ESTIMATE = f(loc);
            if(FREQ_ESTIMATE==0)
               [pks,locs] = findpeaks(Pxx,f,'SortStr','descend');
                FREQ=abs(locs(2))
                title(app.UIAxes4,['Frequency estimate = ',num2str(FREQ),' Hz']);
            else
                 title(app.UIAxes4,['Frequency estimate = ',num2str(FREQ_ESTIMATE),' Hz']);
            end
            fclose(b);
        end

        % Callback function
        function SliderValueChanged(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeScopeTab = uitab(app.TabGroup);
            app.TimeScopeTab.Title = 'Time Scope';
            app.AutoSetButton = uibutton(app.TimeScopeTab, 'push');
            app.AutoSetButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSetButtonPushed, true);
            app.AutoSetButton.Text = 'AutoSet';
            app.TIMEDOMAINLabel = uilabel(app.TimeScopeTab);
            app.TIMEDOMAINLabel.Text = 'TIME DOMAIN';
            app.UIAxes3 = uiaxes(app.TimeScopeTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.FREQUENCYDOMAINLabel = uilabel(app.FFTTab);
            app.FREQUENCYDOMAINLabel.Text = 'FREQUENCY DOMAIN';
            app.UIAxes4 = uiaxes(app.FFTTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = BlueScope

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end